classdef Swabbing_test_application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SavepictureButton  matlab.ui.control.Button
        TakepictureButton  matlab.ui.control.Button
        ImgAx              matlab.ui.control.UIAxes
        ColorAX            matlab.ui.control.UIAxes
    end


    properties (Access = public)
        
     RsPipeLine realsense.pipeline % link to the realsense camera 
     ImgCellArray % Cell array holding the last take image by camera {full colour, red, green ,blue} each is technically stored as a full colour image but with some of the channels set to 0
    end
    
    properties (Access = private)
    end
    
    methods (Access = private)
        
        
        function [Img] = captureAndSplitColourImg(app)
            RsFrameSet = app.RsPipeLine.wait_for_frames();
            cf = RsFrameSet.get_color_frame();
                      
            dd = cf.get_data();
            d = permute(reshape(dd',[3,cf.get_width(),cf.get_height()]),[3 2 1]);
            Img = cell(1, size(d,1)); %create cell array 

            Img{1} = d;

            rImgChannel = d;
            rImgChannel(:,:,2) = 0;
            rImgChannel(:,:,3) = 0;
            Img{2} = rImgChannel;


            gImgChannel = d;
            gImgChannel(:,:,1) = 0;
            gImgChannel(:,:,3) = 0;
            Img{3} = gImgChannel;

            bImgChannel = d;
            bImgChannel(:,:,1) = 0;
            bImgChannel(:,:,2) = 0;
            Img{4} = bImgChannel;
        end


    end
    
    methods (Access = public)


        function success  = SaveCurrentImg(app)
            dateTimeStr = string( datetime("now",'format','dd-MM-yy_HH.mm.SS'));
            dataFilename = append("TestAt ",dateTimeStr);
            dirname = append(string(directoryGet()),"/data/");
            [success ,~,~]=mkdir(append(dirname,dataFilename));
            imwrite(app.ImgCellArray{1},append(dirname,dataFilename,'/FullColourImage.png'));

            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            RsCtx = realsense.context();
            Devs = RsCtx.query_devices();
            if ( numel(Devs) < 1 )
                error ( 'Not found RealSense device' );
            end

            RsDev = Devs{1};
            cn = RsDev.get_info(realsense.camera_info.name);
            delete(RsDev);
            delete(RsCtx);
            app.UIFigure.Name = sprintf('rs_capture_class < Camera : %s >',cn);
            app.RsPipeLine = realsense.pipeline();
            app.RsPipeLine.start();

            app.ImgCellArray = captureAndSplitColourImg(app);
            imshow(app.ImgCellArray{1},'Parent',app.ImgAx);
            montage({app.ImgCellArray{2},app.ImgCellArray{3},app.ImgCellArray{4}}, 'Parent',app.ColorAX,'Size',[1 3]);
          

        end

        % Button pushed function: TakepictureButton
        function TakepictureButtonPushed(app, event)


            app.ImgCellArray = captureAndSplitColourImg(app);
            imshow(app.ImgCellArray{1},'Parent',app.ImgAx);
            montage({app.ImgCellArray{2},app.ImgCellArray{3},app.ImgCellArray{4}}, 'Parent',app.ColorAX,'Size',[1 3]);
          
          
        end

        % Button pushed function: SavepictureButton
        function SavepictureButtonPushed(app, event)
            app.SaveCurrentImg();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ColorAX = uiaxes(app.UIFigure);
            title(app.ColorAX, 'Colour Channels')
            xlabel(app.ColorAX, 'X')
            ylabel(app.ColorAX, 'Y')
            zlabel(app.ColorAX, 'Z')
            app.ImgAx = uiaxes(app.UIFigure);
            title(app.ImgAx, 'Taken image')
            xlabel(app.ImgAx, 'X')
            ylabel(app.ImgAx, 'Y')
            zlabel(app.ImgAx, 'Z')
            app.TakepictureButton = uibutton(app.UIFigure, 'push');
            app.TakepictureButton.ButtonPushedFcn = createCallbackFcn(app, @TakepictureButtonPushed, true);
            app.TakepictureButton.Text = 'Take picture';
            app.SavepictureButton = uibutton(app.UIFigure, 'push');
            app.SavepictureButton.ButtonPushedFcn = createCallbackFcn(app, @SavepictureButtonPushed, true);
            app.SavepictureButton.Text = 'Save picture';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Swabbing_test_application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end